classdef Fitter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FitterUIFigure                 matlab.ui.Figure
        DependentVariablesPanel        matlab.ui.container.Panel
        LoadButton                     matlab.ui.control.Button
        VariablesListBox               matlab.ui.control.ListBox
        ModelPanel                     matlab.ui.container.Panel
        ModelTypeDropDownLabel         matlab.ui.control.Label
        ModelTypeDropDown              matlab.ui.control.DropDown
        PolynomialPowerEditFieldLabel  matlab.ui.control.Label
        PolynomialPowerEditField       matlab.ui.control.NumericEditField
        NormalizationDropDownLabel     matlab.ui.control.Label
        NormalizationDropDown          matlab.ui.control.DropDown
        FitPlay                        matlab.ui.control.Button
        IndependentVariablesPanel      matlab.ui.container.Panel
        LoadButton_2                   matlab.ui.control.Button
        VariablesListBox_2             matlab.ui.control.ListBox
        Panel                          matlab.ui.container.Panel
        PopCVButton                    matlab.ui.control.Button
        PopFitButton_2                 matlab.ui.control.Button
        SaveButton                     matlab.ui.control.Button
        ScatterPlot                    matlab.ui.control.UIAxes
        Panel_2                        matlab.ui.container.Panel
        MeanofErrorEditFieldLabel      matlab.ui.control.Label
        MeanofErrorEditField           matlab.ui.control.NumericEditField
        RSquareLabel                   matlab.ui.control.Label
        RSquareEditField               matlab.ui.control.NumericEditField
        STDofErrorEditFieldLabel       matlab.ui.control.Label
        STDofErrorEditField            matlab.ui.control.NumericEditField
        UITable                        matlab.ui.control.Table
    end


    properties (Access = private)
        DepVariables = [];
        InDepVariables = [];
        FitHistory = [];
        
        CurrentActual = [];
        CurrentPrediction = [];
        CurrentReg = [];
    end

    methods (Access = private)
    
        function plotScatter(app)
            
            R = app.CurrentActual;
            yhat = app.CurrentPrediction;
            
            cla(app.ScatterPlot);
            line(app.ScatterPlot,R,yhat,'Tag','Data','MarkerFaceColor',[1 0 0],...
                'MarkerEdgeColor',[1 0 0],...
                'Marker','o',...
                'LineStyle','none',...
                'Color',[0 0 1]);
            line(app.ScatterPlot,[min([yhat,R]),max([yhat,R])],[min([yhat,R]),max([yhat,R])],'Tag','Reference Ends','LineWidth',3,'color','black');
            axis(app.ScatterPlot, 'tight');
            axis(app.ScatterPlot, 'square');
            grid(app.ScatterPlot, 'on');
            
        end
        
    end


    methods (Access = private)

        % Button pushed function: FitPlay
        function FitPlayButtonPushed(app, event)
            currentParams = cell(1,5);
            
            switch app.ModelTypeDropDown.Value
            
                case 'MultiPolyRegress'
                    currentParams{1} = 'MPR';
                    
                    % Dependent Vars
                    DependentVars = [];
                    if iscell(app.VariablesListBox.Value)
                        DepLength = length(app.DepVariables.(app.VariablesListBox.Value{1}));
                        DependentVars = [];                       
                        DepParamNames = {};
                        
                        for ii = 1:length(app.VariablesListBox.Value)
                            current = app.DepVariables.(app.VariablesListBox.Value{ii});
                                
                            if size(current,2) > size(current,1)
                                current = current';
                            end
                            
                            if size(current,2) ~= 1
                                errordlg('One of the selected variables is not a vector!');
                                return
                            end
                            
                            if size(current,1) ~= DepLength
                                errordlg('One of the selected variables is not the same length as the others!');
                                return
                            end
                        
                            DependentVars = [DependentVars current];
                            DepParamNames{ii} = app.VariablesListBox.Value{ii};
                    
                        end
                        
                        currentParams{2} = char(strjoin(string(DepParamNames),', '));
                    else 
                        DepLength = length(app.DepVariables.(app.VariablesListBox.Value));
                        DependentVars = app.DepVariables.(app.VariablesListBox.Value);
                                     
                        if size(DependentVars,2) > size(DependentVars,1)
                            DependentVars = DependentVars';
                        end
                        
                        if size(DependentVars,2) ~= 1
                            errordlg('One of the selected variables is not a vector!');
                            return
                        end
                        
                        if size(DependentVars,1) ~= DepLength
                            errordlg('One of the selected variables is not the same length as the others!');
                            return
                        end
                        
                        currentParams{2} = app.VariablesListBox.Value;
            
                    end
                
                    % Independent Vars
                    IndependentVar = app.InDepVariables.(app.VariablesListBox_2.Value);
                    
                    if size(IndependentVar,2) > size(IndependentVar,1)
                        IndependentVar = IndependentVar';
                    end
                    
                    if size(IndependentVar,2) ~= 1
                        errordlg('One of the selected variables is not a vector!');
                        return
                    end
                    
                    if size(IndependentVar,1) ~= DepLength
                        errordlg('Selected variable is not the same length as the others!');
                        return
                    end
                    
                    currentParams{3} = app.VariablesListBox_2.Value;
                    
                    % Polynomial Power
                    PW = app.PolynomialPowerEditField.Value;
                    Normalization = app.NormalizationDropDown.Value;
                    
                    currentParams{4} = [num2str(PW),', ',Normalization];
                    
                    reg = MultiPolyRegress(DependentVars,IndependentVar,PW,Normalization);
                    app.CurrentReg = reg;
                    
                    app.CurrentActual = IndependentVar;
                    app.CurrentPrediction = reg.yhat;                  
                    plotScatter(app)
                    
                    currentParams{5} = reg.RSquare;
                    
                    app.MeanofErrorEditField.Value = reg.MAE;
                    app.STDofErrorEditField.Value = reg.MAESTD;
                    app.RSquareEditField.Value = reg.RSquare;
                    
            end
            
            app.UITable.Data = cat(1,app.UITable.Data,currentParams);
            
            
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            app.LoadButton.Enable = 'off';
            [FileName,PathName,~] = uigetfile('*.mat','Select Dataset','PC.mat');
            
            if FileName == 0   
                errordlg('No valid file has been selected!');
                app.LoadButton.Enable = 'on';
                return
            end
            
            FilePath = fullfile(PathName,FileName);
            S = load(FilePath);
            
            app.DepVariables = S;
            DepVarNames = fieldnames(S);
            
            app.VariablesListBox.Items = DepVarNames;     
            
            app.LoadButton.Enable = 'on';
        end

        % Button pushed function: LoadButton_2
        function LoadButton_2Pushed(app, event)
            app.LoadButton_2.Enable = 'off';
            [FileName,PathName,~] = uigetfile('*.mat','Select Dataset','PC.mat');
            
            if FileName == 0   
                errordlg('No valid file has been selected!');
                app.LoadButton_2.Enable = 'on';
                return
            end
            
            FilePath = fullfile(PathName,FileName);
            S = load(FilePath);
            
            app.InDepVariables = S;
            InDepVarNames = fieldnames(S);
            
            app.VariablesListBox_2.Items = InDepVarNames;     
            
            app.LoadButton_2.Enable = 'on';
        end

        % Button pushed function: PopCVButton
        function PopCVButtonPushed(app, event)
            R = app.CurrentActual;
            yhat = R - app.CurrentReg.CVResiduals;
            
            hScatterPlotCV = figure;
            line(R,yhat,'Tag','Data','MarkerFaceColor',[0 0 1],...
                'MarkerEdgeColor',[0 0 1],...
                'Marker','o',...
                'LineStyle','none',...
                'Color',[0 0 1]);
            hold on;
            line([min([yhat,R]),max([yhat,R])],[min([yhat,R]),max([yhat,R])],'Tag','Reference Ends','LineWidth',3,'color','black');
            axis('tight');
            axis('square');
            grid('on');
            
            StatText = sprintf(['R-Square: ', num2str(app.CurrentReg.CVRSquare),'\n', ...
                        'Mean Err: ', num2str(app.CurrentReg.CVMAE),'\n', ...
                        'Std  Err: ', num2str(app.CurrentReg.CVMAESTD),'\n']);
            
            annotation('textbox',[.57 .12 .25 .15],'String',StatText);
            xlabel('Actual');
            ylabel('Prediction');
        end

        % Button pushed function: PopFitButton_2
        function PopFitButton_2Pushed(app, event)
            R = app.CurrentActual;
            yhat = app.CurrentPrediction;
            
            hScatterPlot = figure;
            line(R,yhat,'Tag','Data','MarkerFaceColor',[1 0 0],...
                'MarkerEdgeColor',[1 0 0],...
                'Marker','o',...
                'LineStyle','none',...
                'Color',[0 0 1]);
            hold on;
            line([min([yhat,R]),max([yhat,R])],[min([yhat,R]),max([yhat,R])],'Tag','Reference Ends','LineWidth',3,'color','black');
            axis('tight');
            axis('square');
            grid('on');
            
            StatText = sprintf(['R-Square: ', num2str(app.CurrentReg.RSquare),'\n', ...
                        'Mean Err: ', num2str(app.CurrentReg.MAE),'\n', ...
                        'Std  Err: ', num2str(app.CurrentReg.MAESTD),'\n']);
            
            annotation('textbox',[.57 .12 .25 .15],'String',StatText);
            xlabel('Actual');
            ylabel('Prediction');
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            reg = app.CurrentReg;
            uisave('reg', 'Fit.mat');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FitterUIFigure = uifigure;
            app.FitterUIFigure.Name = 'Fitter';
            app.DependentVariablesPanel = uipanel(app.FitterUIFigure);
            app.DependentVariablesPanel.Title = 'Dependent Variables';
            app.LoadButton = uibutton(app.DependentVariablesPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'open_folder_blue-512.png';
            app.LoadButton.Text = 'Load';
            app.VariablesListBox = uilistbox(app.DependentVariablesPanel);
            app.VariablesListBox.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.VariablesListBox.Multiselect = 'on';
            app.VariablesListBox.Value = {'Item 1'};
            app.ModelPanel = uipanel(app.FitterUIFigure);
            app.ModelPanel.Title = 'Model';
            app.ModelTypeDropDownLabel = uilabel(app.ModelPanel);
            app.ModelTypeDropDownLabel.Text = 'Model Type';
            app.ModelTypeDropDown = uidropdown(app.ModelPanel);
            app.ModelTypeDropDown.Items = {'MultiPolyRegress'};
            app.ModelTypeDropDown.Value = 'MultiPolyRegress';
            app.PolynomialPowerEditFieldLabel = uilabel(app.ModelPanel);
            app.PolynomialPowerEditFieldLabel.Text = 'Polynomial Power';
            app.PolynomialPowerEditField = uieditfield(app.ModelPanel, 'numeric');
            app.PolynomialPowerEditField.Limits = [1 Inf];
            app.PolynomialPowerEditField.Value = 1;
            app.NormalizationDropDownLabel = uilabel(app.ModelPanel);
            app.NormalizationDropDownLabel.Text = 'Normalization';
            app.NormalizationDropDown = uidropdown(app.ModelPanel);
            app.NormalizationDropDown.Items = {'1-to-1', 'Range'};
            app.NormalizationDropDown.Value = '1-to-1';
            app.FitPlay = uibutton(app.ModelPanel, 'push');
            app.FitPlay.ButtonPushedFcn = createCallbackFcn(app, @FitPlayButtonPushed, true);
            app.FitPlay.Icon = 'play.png';
            app.FitPlay.Text = '';
            app.IndependentVariablesPanel = uipanel(app.FitterUIFigure);
            app.IndependentVariablesPanel.Title = 'Independent Variables';
            app.LoadButton_2 = uibutton(app.IndependentVariablesPanel, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_2Pushed, true);
            app.LoadButton_2.Icon = 'open_folder_blue-512.png';
            app.LoadButton_2.Text = 'Load';
            app.VariablesListBox_2 = uilistbox(app.IndependentVariablesPanel);
            app.VariablesListBox_2.Items = {'Item 1', 'Item 2', 'Item 3'};
            app.Panel = uipanel(app.FitterUIFigure);
            app.PopCVButton = uibutton(app.Panel, 'push');
            app.PopCVButton.ButtonPushedFcn = createCallbackFcn(app, @PopCVButtonPushed, true);
            app.PopCVButton.Icon = 'Figure.PNG';
            app.PopCVButton.Text = 'Pop CV';
            app.PopFitButton_2 = uibutton(app.Panel, 'push');
            app.PopFitButton_2.ButtonPushedFcn = createCallbackFcn(app, @PopFitButton_2Pushed, true);
            app.PopFitButton_2.Icon = 'Figure.PNG';
            app.PopFitButton_2.Text = 'Pop Fit';
            app.SaveButton = uibutton(app.Panel, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = 'save.png';
            app.SaveButton.Text = 'Save';
            app.ScatterPlot = uiaxes(app.Panel);
            title(app.ScatterPlot, 'Scatter Plot')
            xlabel(app.ScatterPlot, 'Actual')
            ylabel(app.ScatterPlot, 'Prediction')
            app.Panel_2 = uipanel(app.Panel);
            app.MeanofErrorEditFieldLabel = uilabel(app.Panel_2);
            app.MeanofErrorEditFieldLabel.Text = 'Mean of Error:';
            app.MeanofErrorEditField = uieditfield(app.Panel_2, 'numeric');
            app.MeanofErrorEditField.Editable = 'off';
            app.RSquareLabel = uilabel(app.Panel_2);
            app.RSquareLabel.Text = 'R-Square:';
            app.RSquareEditField = uieditfield(app.Panel_2, 'numeric');
            app.RSquareEditField.Editable = 'off';
            app.STDofErrorEditFieldLabel = uilabel(app.Panel_2);
            app.STDofErrorEditFieldLabel.Text = 'STD of Error:';
            app.STDofErrorEditField = uieditfield(app.Panel_2, 'numeric');
            app.STDofErrorEditField.Editable = 'off';
            app.UITable = uitable(app.FitterUIFigure);
            app.UITable.ColumnName = {'Model'; 'Dependent'; 'Independent'; 'Parameters'; 'R-Square'};
            app.UITable.RowName = {};
        end
    end

    methods (Access = public)

        % Construct app
        function app = Fitter

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FitterUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FitterUIFigure)
        end
    end
end